classdef timNghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KtqunghimLabel                  matlab.ui.control.Label
        SoLanLapEditField               matlab.ui.control.EditField
        KetQuaEditField                 matlab.ui.control.EditField
        SlnlpLabel                      matlab.ui.control.Label
        ChonPhuongPhapDropDown          matlab.ui.control.DropDown
        ChnPhngphpDropDownLabel         matlab.ui.control.Label
        KhoangPhanLyCuoiEditField       matlab.ui.control.NumericEditField
        NhpkhongphnlycuiEditFieldLabel  matlab.ui.control.Label
        SaiSoEditField                  matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel           matlab.ui.control.Label
        KhoangPhanLyEditField           matlab.ui.control.NumericEditField
        NhpkhongphnlynghimuLabel        matlab.ui.control.Label
        KetQuaButton                    matlab.ui.control.Button
        PhuongTrinhEditField            matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KetQuaButton
        function KetQuaButtonPushed(app, event)
            pp = app.ChonPhuongPhapDropDown.Value;

            func_str = app.PhuongTrinhEditField.Value;
            f = str2func(['@(x)' char(func_str)]);
            
            a = app.KhoangPhanLyEditField.Value; 
            
            b = app.KhoangPhanLyCuoiEditField.Value;
            
            saiso = app.SaiSoEditField.Value;
            
           % Chọn phương pháp tính theo input
            if strcmp(pp, 'Newton')
                    [nghiem,solanlap]= PPtieptuyen(f,a,b,saiso)
                    app.SoLanLapEditField.Text = sprintf('Sô lần lặp = %d', solanlap);
                    app.KetQuaEditField.Text = sprintf('Kết quả = %f', nghiem);
                    plot(app.UIAxes, solanlap,nghiem);
            end
            if strcmp(pp, 'Chia đôi')    
                    [nghiem,solanlap] = chiadoi(f,a,b,saiso)
                    app.SoLanLapEditField.Text = sprintf('Sô lần lặp = %d', solanlap);
                    app.KetQuaEditField.Text = sprintf('Kết quả = %f', double(nghiem));
                    plot(app.UIAxes, solanlap, nghiem);
            end
            if strcmp(pp, 'Lặp')
                    [nghiem,solanlap] = PPlap(f,a,b,saiso)
                    app.SoLanLapEditField.Text = sprintf('Sô lần lặp = %d', solanlap);
                    app.KetQuaEditField.Text = sprintf('Kết quả = %f', nghiem);
                    plot(app.UIAxes, solanlap, nghiem);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.UIFigure);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.PhuongTrinhEditField = uieditfield(app.UIFigure, 'text');
            app.KetQuaButton = uibutton(app.UIFigure, 'push');
            app.KetQuaButton.ButtonPushedFcn = createCallbackFcn(app, @KetQuaButtonPushed, true);
            app.KetQuaButton.Text = {'Kết quả nghiệm và vẽ'; ''};
            app.NhpkhongphnlynghimuLabel = uilabel(app.UIFigure);
            app.NhpkhongphnlynghimuLabel.Text = {'Nhập khoảng phân ly nghiệm đầu'; ''};
            app.KhoangPhanLyEditField = uieditfield(app.UIFigure, 'numeric');
            app.NhpsaisEditFieldLabel = uilabel(app.UIFigure);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số';
            app.SaiSoEditField = uieditfield(app.UIFigure, 'numeric');
            app.NhpkhongphnlycuiEditFieldLabel = uilabel(app.UIFigure);
            app.NhpkhongphnlycuiEditFieldLabel.Text = 'Nhập khoảng phân ly cuối';
            app.KhoangPhanLyCuoiEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChnPhngphpDropDownLabel = uilabel(app.UIFigure);
            app.ChnPhngphpDropDownLabel.Text = 'Chọn Phương pháp';
            app.ChonPhuongPhapDropDown = uidropdown(app.UIFigure);
            app.ChonPhuongPhapDropDown.Items = {'Chia đôi', 'Lặp', 'Newton ', ''};
            app.ChonPhuongPhapDropDown.Value = 'Chia đôi';
            app.SlnlpLabel = uilabel(app.UIFigure);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.KetQuaEditField = uieditfield(app.UIFigure, 'text');
            app.SoLanLapEditField = uieditfield(app.UIFigure, 'text');
            app.KtqunghimLabel = uilabel(app.UIFigure);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timNghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end